classdef pid_main_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DewanLabPIDUIFigure            matlab.ui.Figure
        PID_Controls_2                 matlab.ui.container.Panel
        STOPButton_5                   matlab.ui.control.Button
        OrthoButton                    matlab.ui.control.Button
        RetroButton                    matlab.ui.control.Button
        Cal_Controls                   matlab.ui.container.Panel
        STOPButton_4                   matlab.ui.control.Button
        RunCalibrationButton           matlab.ui.control.Button
        Kinetics_Controls              matlab.ui.container.Panel
        STOPButton                     matlab.ui.control.Button
        RunKineticsButton              matlab.ui.control.Button
        PID_Controls                   matlab.ui.container.Panel
        STOPButton_3                   matlab.ui.control.Button
        SOLVENTSingleTrialButton       matlab.ui.control.Button
        ConcentrationAnalysisMOButton  matlab.ui.control.Button
        ConcentrationAnalysisSOLVENTButton  matlab.ui.control.Button
        Sensor_Status                  matlab.ui.container.Panel
        VoltageVGauge                  matlab.ui.control.SemicircularGauge
        VoltageVGaugeLabel             matlab.ui.control.Label
        PIDPeggedLamp                  matlab.ui.control.Lamp
        PIDPeggedLampLabel             matlab.ui.control.Label
        CF_Controls                    matlab.ui.container.Panel
        STOPButton_2                   matlab.ui.control.Button
        PureTrialButton                matlab.ui.control.Button
        CorrectionFactorButton         matlab.ui.control.Button
        ExperimentParametersPanel      matlab.ui.container.Panel
        ITImsEditField                 matlab.ui.control.NumericEditField
        ITImsEditFieldLabel            matlab.ui.control.Label
        LiveStatisticsPanel            matlab.ui.container.Panel
        FALSELabel                     matlab.ui.control.Label
        EstiamtedPPMEditField          matlab.ui.control.NumericEditField
        EstiamtedPPMEditFieldLabel     matlab.ui.control.Label
        SteadyStateLabel               matlab.ui.control.Label
        OdorPredurationmsEditField     matlab.ui.control.NumericEditField
        OdorPredurationLabel           matlab.ui.control.Label
        TrialPredurationmsEditField    matlab.ui.control.NumericEditField
        TrialPredurationLabel          matlab.ui.control.Label
        OdorDurationmsEditField        matlab.ui.control.NumericEditField
        OdorDurationLabel              matlab.ui.control.Label
        SubsampleEditField             matlab.ui.control.NumericEditField
        SubsampleLabel                 matlab.ui.control.Label
        NumofTrialsEditField           matlab.ui.control.NumericEditField
        NumofTrialsLabel               matlab.ui.control.Label
        PIDPumpDropDown                matlab.ui.control.DropDown
        PIDPumpDropDownLabel           matlab.ui.control.Label
        PIDGainDropDown                matlab.ui.control.DropDown
        PIDGainLabel                   matlab.ui.control.Label
        TrialTypeDropDown              matlab.ui.control.DropDown
        TrialTypeLabel                 matlab.ui.control.Label
        SpacerEditField                matlab.ui.control.NumericEditField
        SpacerLabel                    matlab.ui.control.Label
        ConcentrationVolumeEditField   matlab.ui.control.NumericEditField
        ConcentrationVolumeLabel       matlab.ui.control.Label
        DilutorMFCEditField            matlab.ui.control.NumericEditField
        DilutorMFCLabel                matlab.ui.control.Label
        CarrierMFCEditField            matlab.ui.control.NumericEditField
        CarrierMFCLabel                matlab.ui.control.Label
        ManualControlPanel             matlab.ui.container.Panel
        ISBPanel                       matlab.ui.container.Panel
        ISBTrialButton                 matlab.ui.control.Button
        CurrentTrialNumber             matlab.ui.control.Label
        CurrentTrialLabel              matlab.ui.control.Label
        OdorNameLabel                  matlab.ui.control.Label
        OdorLabel                      matlab.ui.control.Label
        VialValvesPanel                matlab.ui.container.Panel
        PresurizationPanel             matlab.ui.container.Panel
        PressurizeOdorLamp             matlab.ui.control.Lamp
        PressurizeOdorVialSwitch       matlab.ui.control.Switch
        PressurizeOdorVialSwitchLabel  matlab.ui.control.Label
        PressurizeSolventLamp          matlab.ui.control.Lamp
        PressurizeSolventVialSwitch    matlab.ui.control.Switch
        PressurizeSolventVialSwitchLabel  matlab.ui.control.Label
        OdorVialBottlePanel            matlab.ui.container.Panel
        Valve3Lamp                     matlab.ui.control.Lamp
        Valve1Switch_2                 matlab.ui.control.Switch
        Valve1Switch_2Label            matlab.ui.control.Label
        Valve4Lamp                     matlab.ui.control.Lamp
        Valve2Switch_2                 matlab.ui.control.Switch
        Valve2Switch_2Label            matlab.ui.control.Label
        SolventPureVialPanel           matlab.ui.container.Panel
        Valve1Lamp                     matlab.ui.control.Lamp
        Valve1Switch                   matlab.ui.control.Switch
        Valve1SwitchLabel              matlab.ui.control.Label
        Valve2Lamp                     matlab.ui.control.Lamp
        Valve2Switch                   matlab.ui.control.Switch
        Valve2SwitchLabel              matlab.ui.control.Label
        FinalValvesPanel               matlab.ui.container.Panel
        FinalValve2Switch              matlab.ui.control.Switch
        FinalValve2SwitchLabel         matlab.ui.control.Label
        FV2Lamp                        matlab.ui.control.Lamp
        OdorFVLamp                     matlab.ui.control.Lamp
        OdorFinalValveSwitch           matlab.ui.control.Switch
        OdorFinalValveSwitchLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        pegged = false;
    end
    
    properties (Access = private)
        start_pid_callback = [];
        valve_control_callback = [];
        soft_close_callback = []
        current_control_panel = [];
        attempt_to_quit_counter = 0;
        matlab_version = 0;
        off_state = false;
        on_state = true;
        original_trials =[];
        original_type = [];
        DEBUG = false; 
    end
    
    methods (Access = public)
        
        function update_voltage(app, value)
            if value > 10
                value = 10;
                app.PIDPeggedLamp.Color = [1 0 0];
                app.pegged = true;
            elseif value < 0
                value = 0;
            end

           % app.animate_voltage(value);
           app.VoltageVGauge.Value = value;
           return;
            
        end

        function update_PPM(app, value)
            app.EstiamtedPPMEditField.Value = value;
        end
   
        function update_current_trial(app, value)
            app.CurrentTrialNumber.Text = value;
        end

        function params = get_params(app, ~)
            
            params = {};
            
            params.ITIms = app.ITImsEditField.Value;
            params.odor_preduration = app.OdorPredurationmsEditField.Value;
            params.odor_duration = app.OdorDurationmsEditField.Value;
            params.trial_preduration = app.TrialPredurationmsEditField.Value;
            params.subsamples = app.SubsampleEditField.Value;
            params.number_of_trials = app.NumofTrialsEditField.Value;
            params.pid_pump = app.PIDPumpDropDown.Value;
            params.pid_gain = app.PIDGainDropDown.Value;
            params.trial_type = app.TrialTypeDropDown.Value;
            params.spacer = app.SpacerEditField.Value;
            params.concentration_volume = app.ConcentrationVolumeEditField.Value;
            params.dilutor_MFC = app.DilutorMFCEditField.Value;
            params.carrier_MFC = app.CarrierMFCEditField.Value;

        end
        
        function lock_gui(app)
            app.ExperimentParametersPanel.Enable = app.off_state;
            app.ManualControlPanel.Enable = app.off_state;
            app.ISBPanel.Enable = app.off_state;
        end

        function unlock_gui(app)
            app.ExperimentParametersPanel.Enable = app.on_state;
            app.ManualControlPanel.Enable = app.on_state;
            app.ISBPanel.Enable = app.on_state;

        end
        
    end
    
    methods (Access = private) 
        function animate_voltage(app, value)
            current_val = app.VoltageVGauge.Value;
            steps = linspace(current_val, value, 10);

            if current_val == value
                return
            else
                for i = 1:length(steps)
                    app.VoltageVGauge.Value = steps(i);
                    pause(0.02);
                end
            end
        end
       
        
        function manual_valve_control(app, valve, state)
            switch(state)
                case "On"
                    app.valve_control_callback(valve, 1);
                case "Off"
                    app.valve_control_callback(valve, 0);
            end  
        end

        function lock_controls(app)
            app.current_control_panel.Enable = "off";
        end

        function unlock_controls(app)
            app.current_control_panel.Enable = "on";
        end
        
        
        function force_close(app)
            % In the unfortunate circumstance that something hangs, we can
            % force close the GUI and DC the modules from here.
            % We prefer this happening from the main thread to make sure
            % everything has finished and is in a happy state
            
            global BpodSystem;
            BpodSystem.PluginObjects.a_in = [];
            try
                evalin('base', 'EndBpod;')
            catch
                disp(['Error gracefully shutting down Bpod. ' ...
                    'Force closing GUI and clearing workspace!!']);
                evalin('base', 'close all force');
                BpodSystem = [];
                evalin('base', 'clear');
            end
            delete(app)
        end


        function set_version_variables(app)
            % old matlab versions use on/off instead of true/false for
            % different GUI properties
            vers = version; % Run Version Command
            app.matlab_version = split(vers, '.'); % Split by periods

            if app.matlab_version{1} == '9' % Check major vers 9 = 2021
                app.off_state = 'off';
                app.on_state = 'on';
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, startup_params, start_PID_callback, valve_control_callback, soft_close_callback)
            
            if (~isa(start_PID_callback, 'function_handle')) && (~isa(valve_control_callback, 'function_handle')) && (~isa(soft_close_callback, 'function_handle'))
                disp("Starting GUI in DEBUG mode!");
                app.DEBUG = true;
            end

            if ~ app.DEBUG
                if ~ isempty(startup_params)
                    trial_type = startup_params.session_type;
                end
                switch(trial_type)
                    case "PID2"
                        app.PID_Controls_2.Visible = app.on_state;
                        app.PID_Controls_2.Enable = app.off_state;
                        app.SubsampleEditField.Value = 0;
                        app.SubsampleEditField.Enable = false;
                        app.NumofTrialsEditField.Value = 5;
                        app.current_control_panel = app.PID_Controls_2;
                        app.ISBPanel.Enable = app.off_state;
                        app.ISBPanel.Visible = app.off_state;
                        app.FV2Lamp.Enable = app.on_state;
                        app.FV2Lamp.Visible = app.on_state;
                        app.FinalValve2Switch.Enable = app.on_state;
                        app.FinalValve2Switch.Visible = app.on_state;
                    case "PID"
                        app.PID_Controls.Visible = "on";
                        app.PID_Controls.Enable = "on";
                        app.SubsampleEditField.Value = 0;
                        app.SubsampleEditField.Enable = false;
                        app.NumofTrialsEditField.Value = 5;
                        app.current_control_panel = app.PID_Controls;
                    case "CF"
                        app.CF_Controls.Visible = "on";
                        app.CF_Controls.Enable = "on";
                        app.SubsampleEditField.Value = 0;
                        app.SubsampleEditField.Enable = false;
                        app.NumofTrialsEditField.Value = 0;
                        app.NumofTrialsEditField.Enable = false;
                        app.current_control_panel = app.CF_Controls;
    
                        % Some CF default values
                        app.CarrierMFCEditField.Value = 900;
                        app.DilutorMFCEditField.Value = 0;
                        app.PIDGainDropDown.Value = 'x1';
                    case "Kinetics"
                        app.Kinetics_Controls.Visible = "on";
                        app.Kinetics_Controls.Enable = "on";
                        app.NumofTrialsEditField.Value = 250;
                        app.current_control_panel = app.Kinetics_Controls;
                    case "Calibration"
                        app.Cal_Controls.Visible = "on";
                        app.Cal_Controls.Enable = "on";
                        app.NumofTrialsEditField.Value = 5;
                        app.PIDGainDropDown.Value = 'x10';
                        app.SubsampleEditField.Value = 0;
                        app.SubsampleEditField.Enable = false;
                        app.current_control_panel = app.Kinetics_Controls;
                        app.TrialTypeDropDown.Value = 'Calibration';
                end
    
                app.OdorNameLabel.Text = startup_params.odor;
                app.start_pid_callback = start_PID_callback;
                app.soft_close_callback = soft_close_callback;
                app.valve_control_callback = valve_control_callback;
    
                app.set_version_variables();
            else
                % We are in DEBUG Mode
                app.OdorNameLabel.Text = 'DEBUG';
                app.start_pid_callback = [];
                app.soft_close_callback = [];
                app.valve_control_callback = [];

            end


        end

        % Value changed function: OdorFinalValveSwitch
        function OdorFinalValveSwitchValueChanged(app, event)
            value = app.OdorFinalValveSwitch.Value;
            if value == "On"
                app.OdorFVLamp.Color = [0, 1, 0];
                app.lock_controls;
            else
                app.OdorFVLamp.Color = [0.65, 0.65, 0.65];
                app.unlock_controls;
            end

            manual_valve_control(app, 1, value);
        end

        % Value changed function: Valve1Switch
        function Valve1SwitchValueChanged(app, event)
            value = app.Valve1Switch.Value;
            if value == "On"
                app.Valve1Lamp.Color = [0, 1, 0];
                app.lock_controls;
            else
                app.Valve1Lamp.Color = [0.65, 0.65, 0.65];
                app.unlock_controls;
            end

            manual_valve_control(app, 5, value);

        end

        % Value changed function: Valve2Switch
        function Valve2SwitchValueChanged(app, event)
            value = app.Valve2Switch.Value;
            if value == "On"
                app.Valve2Lamp.Color = [0, 1, 0];
                app.lock_controls;
            else
                app.Valve2Lamp.Color = [0.65, 0.65, 0.65];
                app.unlock_controls;
            end
            
            manual_valve_control(app, 6, value);

        end

        % Value changed function: Valve1Switch_2
        function Valve1Switch_2ValueChanged(app, event)
            value = app.Valve1Switch_2.Value;

            if value == "On"
                app.Valve3Lamp.Color = [0, 1, 0];
                app.lock_controls;
            else
                app.Valve3Lamp.Color = [0.65, 0.65, 0.65];
                app.unlock_controls;
            end

            manual_valve_control(app, 7, value);

        end

        % Value changed function: Valve2Switch_2
        function Valve2Switch_2ValueChanged(app, event)
            
            value = app.Valve2Switch_2.Value;
            if value == "On"
                app.Valve4Lamp.Color = [0, 1, 0];
                app.lock_controls;
            else
                app.Valve4Lamp.Color = [0.65, 0.65, 0.65];
                app.unlock_controls;
            end

            manual_valve_control(app, 8, value);

        end

        % Value changed function: PressurizeOdorVialSwitch
        function PressurizeOdorVialSwitchValueChanged(app, event)
            value = app.PressurizeOdorVialSwitch.Value;
            if value == "On"
                app.PressurizeOdorLamp.Color = [0, 1, 0];
                app.Valve1Switch_2.Value = "On";
                app.Valve2Switch_2.Value = "On";
                app.Valve1Switch_2.Enable = false;
                app.Valve2Switch_2.Enable = false;
                app.Valve1Switch_2ValueChanged();
                app.Valve2Switch_2ValueChanged();
            else
                app.PressurizeOdorLamp.Color = [0.65, 0.65, 0.65];
                app.Valve1Switch_2.Value = "Off";
                app.Valve2Switch_2.Value = "Off";
                app.Valve1Switch_2.Enable = true;
                app.Valve2Switch_2.Enable = true;
                app.Valve1Switch_2ValueChanged();
                app.Valve2Switch_2ValueChanged();
            end
        end

        % Value changed function: PressurizeSolventVialSwitch
        function PressurizeSolventVialSwitchValueChanged(app, event)
            value = app.PressurizeSolventVialSwitch.Value;
            if value == "On"
                app.PressurizeSolventLamp.Color = [0, 1, 0];
                app.Valve1Switch.Value = "On";
                app.Valve2Switch.Value = "On";
                app.Valve1Switch.Enable = false;
                app.Valve2Switch.Enable = false;
                app.Valve1SwitchValueChanged();
                app.Valve2SwitchValueChanged();
            else
                app.PressurizeSolventLamp.Color = [0.65, 0.65, 0.65];
                app.Valve1Switch.Value = "Off";
                app.Valve2Switch.Value = "Off";
                app.Valve1Switch.Enable = true;
                app.Valve2Switch.Enable = true;
                app.Valve1SwitchValueChanged();
                app.Valve2SwitchValueChanged();
            end
        end

        % Close request function: DewanLabPIDUIFigure
        function close_callback(app, event)
            global BpodSystem; 
            BpodSystem.Status.BeingUsed = 0; % Stop Bpod when exit is clicked

            if app.attempt_to_quit_counter >=3
                quit = questdlg("Would you like to force-close the GUI", ...
                    "Force Close", "Yes", "No", "Yes");
           
                switch quit
                    case 'Yes'
                        app.force_close()
                end
            end

            answer = questdlg("Are you sure you want to quit? This will close the GUI and end the experiment!", "Quit?", "Yes, quit!", "No, continue!", "No, continue!");
            switch answer
                case 'Yes, quit!'
                    app.attempt_to_quit_counter = app.attempt_to_quit_counter + 1;
                    try
                        if BpodSystem.Status.SafeClose == 1 % If state machine is idling, we can initialize soft close here
                            app.soft_close_callback(app);
                        else
                            BpodSystem.Status.ShutdownFlag = 1; % Set flag so main thread can handle soft shutdown
                        end
                    catch
                    end
                case 'No, continue!'
                    app.attempt_to_quit_counter = 0;
                    return
            end


        end

        % Button pushed function: STOPButton, STOPButton_2, STOPButton_3, 
        % ...and 2 other components
        function STOPButtonPushed(app, event)
            global BpodSystem;
            BpodSystem.Status.BeingUsed = 0;
            app.unlock_gui();
        end

        % Button pushed function: SOLVENTSingleTrialButton
        function SOLVENTSingleTrialButtonPushed(app, event)
            app.TrialTypeDropDown.Value = 'Solvent';
            app.NumofTrialsEditField.Value = 1;
            app.start_pid_callback([],[],app);
        end

        % Button pushed function: CorrectionFactorButton
        function CorrectionFactorButtonPushed(app, event)
           app.TrialTypeDropDown.Value = 'Odor';
           app.start_pid_callback([],[],app);
        end

        % Button pushed function: PureTrialButton
        function PureTrialButtonPushed(app, event)
           app.TrialTypeDropDown.Value = 'Pure';
           app.NumofTrialsEditField.Value = 1; 
           app.start_pid_callback([],[],app);
        end

        % Callback function: not associated with a component
        function ISBTrialButtonPushed(app, event)
           app.TrialTypeDropDown.Value = 'ISB';
           app.NumofTrialsEditField.Value = 1; 
           app.start_pid_callback([],[],app);
        end

        % Button pushed function: RunCalibrationButton
        function RunCalibrationButtonPushed(app, event)
            app.TrialTypeDropDown.Value = 'Calibration';
            app.start_pid_callback([],[],app);
        end

        % Button pushed function: RunKineticsButton
        function RunKineticsButtonPushed(app, event)
            app.TrialTypeDropDown.Value = 'Odor';
            app.start_pid_callback([],[],app);
        end

        % Button pushed function: ConcentrationAnalysisMOButton
        function ConcentrationAnalysisMOButtonPushed(app, event)
            app.TrialTypeDropDown.Value = 'Odor';
            app.start_pid_callback([], [], app);
        end

        % Button pushed function: ConcentrationAnalysisSOLVENTButton
        function ConcentrationAnalysisSOLVENTButtonPushed(app, event)
            app.TrialTypeDropDown.Value = 'Solvent';
            app.start_pid_callback([],[],app);
        end

        % Value changed function: FinalValve2Switch
        function FinalValve2SwitchValueChanged(app, event)
            value = app.FinalValve2Switch.Value;
            if value == "On"
                app.FV2Lamp.Color = [0, 1, 0];
                app.lock_controls;
            else
                app.FV2Lamp.Color = [0.65, 0.65, 0.65];
                app.unlock_controls;
            end

            manual_valve_control(app, 3, value);
        end

        % Button pushed function: RetroButton
        function RetroButtonPushed(app, event)
            app.TrialTypeDropDown.Value = 'Retro';
            app.start_pid_callback([],[],app);
        end

        % Button pushed function: OrthoButton
        function OrthoButtonPushed(app, event)
            app.TrialTypeDropDown.Value = 'Ortho';
            app.start_pid_callback([],[],app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DewanLabPIDUIFigure = uifigure('Visible', 'off');
            app.DewanLabPIDUIFigure.Name = 'Dewan Lab PID';
            app.DewanLabPIDUIFigure.CloseRequestFcn = createCallbackFcn(app, @close_callback, true);
            app.ManualControlPanel = uipanel(app.DewanLabPIDUIFigure);
            app.ManualControlPanel.Title = 'Manual Control';
            app.FinalValvesPanel = uipanel(app.ManualControlPanel);
            app.FinalValvesPanel.Title = 'Final Valves';
            app.OdorFinalValveSwitchLabel = uilabel(app.FinalValvesPanel);
            app.OdorFinalValveSwitchLabel.Text = 'Odor Final Valve';
            app.OdorFinalValveSwitch = uiswitch(app.FinalValvesPanel, 'slider');
            app.OdorFinalValveSwitch.ValueChangedFcn = createCallbackFcn(app, @OdorFinalValveSwitchValueChanged, true);
            app.OdorFVLamp = uilamp(app.FinalValvesPanel);
            app.FV2Lamp = uilamp(app.FinalValvesPanel);
            app.FV2Lamp.Enable = 'off';
            app.FV2Lamp.Visible = 'off';
            app.FinalValve2SwitchLabel = uilabel(app.FinalValvesPanel);
            app.FinalValve2SwitchLabel.Text = 'Final Valve 2';
            app.FinalValve2Switch = uiswitch(app.FinalValvesPanel, 'slider');
            app.FinalValve2Switch.ValueChangedFcn = createCallbackFcn(app, @FinalValve2SwitchValueChanged, true);
            app.FinalValve2Switch.Enable = 'off';
            app.FinalValve2Switch.Visible = 'off';
            app.VialValvesPanel = uipanel(app.ManualControlPanel);
            app.VialValvesPanel.Title = 'Vial Valves';
            app.SolventPureVialPanel = uipanel(app.VialValvesPanel);
            app.SolventPureVialPanel.Title = 'Solvent / Pure Vial';
            app.Valve2SwitchLabel = uilabel(app.SolventPureVialPanel);
            app.Valve2SwitchLabel.Text = 'Valve 2';
            app.Valve2Switch = uiswitch(app.SolventPureVialPanel, 'slider');
            app.Valve2Switch.ValueChangedFcn = createCallbackFcn(app, @Valve2SwitchValueChanged, true);
            app.Valve2Lamp = uilamp(app.SolventPureVialPanel);
            app.Valve1SwitchLabel = uilabel(app.SolventPureVialPanel);
            app.Valve1SwitchLabel.Text = 'Valve 1';
            app.Valve1Switch = uiswitch(app.SolventPureVialPanel, 'slider');
            app.Valve1Switch.ValueChangedFcn = createCallbackFcn(app, @Valve1SwitchValueChanged, true);
            app.Valve1Lamp = uilamp(app.SolventPureVialPanel);
            app.OdorVialBottlePanel = uipanel(app.VialValvesPanel);
            app.OdorVialBottlePanel.Title = 'Odor Vial / Bottle';
            app.Valve2Switch_2Label = uilabel(app.OdorVialBottlePanel);
            app.Valve2Switch_2Label.Text = 'Valve 2';
            app.Valve2Switch_2 = uiswitch(app.OdorVialBottlePanel, 'slider');
            app.Valve2Switch_2.ValueChangedFcn = createCallbackFcn(app, @Valve2Switch_2ValueChanged, true);
            app.Valve4Lamp = uilamp(app.OdorVialBottlePanel);
            app.Valve1Switch_2Label = uilabel(app.OdorVialBottlePanel);
            app.Valve1Switch_2Label.Text = 'Valve 1';
            app.Valve1Switch_2 = uiswitch(app.OdorVialBottlePanel, 'slider');
            app.Valve1Switch_2.ValueChangedFcn = createCallbackFcn(app, @Valve1Switch_2ValueChanged, true);
            app.Valve3Lamp = uilamp(app.OdorVialBottlePanel);
            app.PresurizationPanel = uipanel(app.VialValvesPanel);
            app.PresurizationPanel.Title = 'Presurization';
            app.PressurizeSolventVialSwitchLabel = uilabel(app.PresurizationPanel);
            app.PressurizeSolventVialSwitchLabel.Text = 'Pressurize Solvent Vial';
            app.PressurizeSolventVialSwitch = uiswitch(app.PresurizationPanel, 'slider');
            app.PressurizeSolventVialSwitch.ValueChangedFcn = createCallbackFcn(app, @PressurizeSolventVialSwitchValueChanged, true);
            app.PressurizeSolventLamp = uilamp(app.PresurizationPanel);
            app.PressurizeOdorVialSwitchLabel = uilabel(app.PresurizationPanel);
            app.PressurizeOdorVialSwitchLabel.Text = 'Pressurize Odor Vial';
            app.PressurizeOdorVialSwitch = uiswitch(app.PresurizationPanel, 'slider');
            app.PressurizeOdorVialSwitch.ValueChangedFcn = createCallbackFcn(app, @PressurizeOdorVialSwitchValueChanged, true);
            app.PressurizeOdorLamp = uilamp(app.PresurizationPanel);
            app.OdorLabel = uilabel(app.ManualControlPanel);
            app.OdorLabel.Text = 'Odor: ';
            app.OdorNameLabel = uilabel(app.ManualControlPanel);
            app.OdorNameLabel.Text = 'Odor Name';
            app.CurrentTrialLabel = uilabel(app.ManualControlPanel);
            app.CurrentTrialLabel.Text = 'Current Trial:';
            app.CurrentTrialNumber = uilabel(app.ManualControlPanel);
            app.CurrentTrialNumber.Text = '0';
            app.ISBPanel = uipanel(app.ManualControlPanel);
            app.ISBPanel.Enable = 'off';
            app.ISBPanel.Title = 'Isobutylene';
            app.ISBPanel.Visible = 'off';
            app.ISBTrialButton = uibutton(app.ISBPanel, 'push');
            app.ISBTrialButton.Text = 'ISB Trial';
            app.ExperimentParametersPanel = uipanel(app.DewanLabPIDUIFigure);
            app.ExperimentParametersPanel.Title = 'Experiment Parameters';
            app.CarrierMFCLabel = uilabel(app.ExperimentParametersPanel);
            app.CarrierMFCLabel.Text = 'Carrier MFC:';
            app.CarrierMFCEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.CarrierMFCEditField.Limits = [0 900];
            app.CarrierMFCEditField.RoundFractionalValues = 'on';
            app.CarrierMFCEditField.Value = 300;
            app.DilutorMFCLabel = uilabel(app.ExperimentParametersPanel);
            app.DilutorMFCLabel.Text = 'Dilutor MFC:';
            app.DilutorMFCEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.DilutorMFCEditField.Limits = [0 900];
            app.DilutorMFCEditField.RoundFractionalValues = 'on';
            app.DilutorMFCEditField.Value = 600;
            app.ConcentrationVolumeLabel = uilabel(app.ExperimentParametersPanel);
            app.ConcentrationVolumeLabel.Text = 'Concentration / Volume:';
            app.ConcentrationVolumeEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.ConcentrationVolumeEditField.Limits = [0 Inf];
            app.SpacerLabel = uilabel(app.ExperimentParametersPanel);
            app.SpacerLabel.Text = 'Spacer:';
            app.SpacerEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.SpacerEditField.Limits = [0 Inf];
            app.SpacerEditField.Editable = 'off';
            app.SpacerEditField.Value = 0.375;
            app.TrialTypeLabel = uilabel(app.ExperimentParametersPanel);
            app.TrialTypeLabel.Text = 'Trial Type:';
            app.TrialTypeDropDown = uidropdown(app.ExperimentParametersPanel);
            app.TrialTypeDropDown.Items = {'Odor', 'Solvent', 'Pure', 'ISB', 'Calibration', 'Ortho', 'Retro'};
            app.TrialTypeDropDown.Value = 'Ortho';
            app.PIDGainLabel = uilabel(app.ExperimentParametersPanel);
            app.PIDGainLabel.Text = 'PID Gain:';
            app.PIDGainDropDown = uidropdown(app.ExperimentParametersPanel);
            app.PIDGainDropDown.Items = {'x10', 'x5', 'x1'};
            app.PIDGainDropDown.Value = 'x10';
            app.PIDPumpDropDownLabel = uilabel(app.ExperimentParametersPanel);
            app.PIDPumpDropDownLabel.Text = 'PID Pump:';
            app.PIDPumpDropDown = uidropdown(app.ExperimentParametersPanel);
            app.PIDPumpDropDown.Items = {'low', 'med', 'high'};
            app.PIDPumpDropDown.Value = 'low';
            app.NumofTrialsLabel = uilabel(app.ExperimentParametersPanel);
            app.NumofTrialsLabel.Text = 'Num. of Trials:';
            app.NumofTrialsEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.NumofTrialsEditField.Limits = [0 Inf];
            app.NumofTrialsEditField.RoundFractionalValues = 'on';
            app.NumofTrialsEditField.Value = 250;
            app.SubsampleLabel = uilabel(app.ExperimentParametersPanel);
            app.SubsampleLabel.Text = 'Subsample:';
            app.SubsampleEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.SubsampleEditField.Limits = [0 Inf];
            app.SubsampleEditField.RoundFractionalValues = 'on';
            app.SubsampleEditField.Value = 5;
            app.OdorDurationLabel = uilabel(app.ExperimentParametersPanel);
            app.OdorDurationLabel.Text = 'Odor Duration (ms):';
            app.OdorDurationmsEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.OdorDurationmsEditField.Limits = [0 Inf];
            app.OdorDurationmsEditField.RoundFractionalValues = 'on';
            app.OdorDurationmsEditField.Value = 2000;
            app.TrialPredurationLabel = uilabel(app.ExperimentParametersPanel);
            app.TrialPredurationLabel.Text = 'Trial Preduration (ms):';
            app.TrialPredurationmsEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.TrialPredurationmsEditField.Limits = [0 Inf];
            app.TrialPredurationmsEditField.RoundFractionalValues = 'on';
            app.TrialPredurationmsEditField.Value = 1000;
            app.OdorPredurationLabel = uilabel(app.ExperimentParametersPanel);
            app.OdorPredurationLabel.Text = 'Odor Preduration (ms):';
            app.OdorPredurationmsEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.OdorPredurationmsEditField.Limits = [0 Inf];
            app.OdorPredurationmsEditField.RoundFractionalValues = 'on';
            app.OdorPredurationmsEditField.Value = 600;
            app.LiveStatisticsPanel = uipanel(app.ExperimentParametersPanel);
            app.LiveStatisticsPanel.Title = 'Live Statistics';
            app.SteadyStateLabel = uilabel(app.LiveStatisticsPanel);
            app.SteadyStateLabel.Text = 'Steady State:';
            app.EstiamtedPPMEditFieldLabel = uilabel(app.LiveStatisticsPanel);
            app.EstiamtedPPMEditFieldLabel.Text = 'Estiamted PPM:';
            app.EstiamtedPPMEditField = uieditfield(app.LiveStatisticsPanel, 'numeric');
            app.EstiamtedPPMEditField.Limits = [0 Inf];
            app.EstiamtedPPMEditField.RoundFractionalValues = 'on';
            app.EstiamtedPPMEditField.Value = 1000;
            app.FALSELabel = uilabel(app.LiveStatisticsPanel);
            app.FALSELabel.Text = 'FALSE';
            app.ITImsEditFieldLabel = uilabel(app.ExperimentParametersPanel);
            app.ITImsEditFieldLabel.Text = 'ITI (ms):';
            app.ITImsEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.ITImsEditField.Limits = [0 Inf];
            app.ITImsEditField.RoundFractionalValues = 'on';
            app.ITImsEditField.ValueDisplayFormat = '%.0f';
            app.ITImsEditField.Value = 15000;
            app.CF_Controls = uipanel(app.DewanLabPIDUIFigure);
            app.CF_Controls.Enable = 'off';
            app.CF_Controls.Title = 'Experiment Controls (CF)';
            app.CF_Controls.Visible = 'off';
            app.CorrectionFactorButton = uibutton(app.CF_Controls, 'push');
            app.CorrectionFactorButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectionFactorButtonPushed, true);
            app.CorrectionFactorButton.Text = 'Correction Factor';
            app.PureTrialButton = uibutton(app.CF_Controls, 'push');
            app.PureTrialButton.ButtonPushedFcn = createCallbackFcn(app, @PureTrialButtonPushed, true);
            app.PureTrialButton.Text = 'Pure Trial';
            app.STOPButton_2 = uibutton(app.CF_Controls, 'push');
            app.STOPButton_2.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton_2.Text = 'STOP';
            app.Sensor_Status = uipanel(app.DewanLabPIDUIFigure);
            app.Sensor_Status.Title = 'Sensor Status';
            app.PIDPeggedLampLabel = uilabel(app.Sensor_Status);
            app.PIDPeggedLampLabel.Text = 'PID Pegged:';
            app.PIDPeggedLamp = uilamp(app.Sensor_Status);
            app.VoltageVGaugeLabel = uilabel(app.Sensor_Status);
            app.VoltageVGaugeLabel.Text = 'Voltage (V)';
            app.VoltageVGauge = uigauge(app.Sensor_Status, 'semicircular');
            app.VoltageVGauge.Limits = [0 10];
            app.PID_Controls = uipanel(app.DewanLabPIDUIFigure);
            app.PID_Controls.Enable = 'off';
            app.PID_Controls.Title = 'Experiment Controls (PID)';
            app.PID_Controls.Visible = 'off';
            app.ConcentrationAnalysisSOLVENTButton = uibutton(app.PID_Controls, 'push');
            app.ConcentrationAnalysisSOLVENTButton.ButtonPushedFcn = createCallbackFcn(app, @ConcentrationAnalysisSOLVENTButtonPushed, true);
            app.ConcentrationAnalysisSOLVENTButton.Enable = 'off';
            app.ConcentrationAnalysisSOLVENTButton.Text = 'Concentration Analysis (SOLVENT)';
            app.ConcentrationAnalysisMOButton = uibutton(app.PID_Controls, 'push');
            app.ConcentrationAnalysisMOButton.ButtonPushedFcn = createCallbackFcn(app, @ConcentrationAnalysisMOButtonPushed, true);
            app.ConcentrationAnalysisMOButton.Text = 'Concentration Analysis (MO)';
            app.SOLVENTSingleTrialButton = uibutton(app.PID_Controls, 'push');
            app.SOLVENTSingleTrialButton.ButtonPushedFcn = createCallbackFcn(app, @SOLVENTSingleTrialButtonPushed, true);
            app.SOLVENTSingleTrialButton.Text = 'SOLVENT: Single Trial';
            app.STOPButton_3 = uibutton(app.PID_Controls, 'push');
            app.STOPButton_3.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton_3.Text = 'STOP';
            app.Kinetics_Controls = uipanel(app.DewanLabPIDUIFigure);
            app.Kinetics_Controls.Enable = 'off';
            app.Kinetics_Controls.Title = 'Experiment Controls (Kinetics)';
            app.Kinetics_Controls.Visible = 'off';
            app.RunKineticsButton = uibutton(app.Kinetics_Controls, 'push');
            app.RunKineticsButton.ButtonPushedFcn = createCallbackFcn(app, @RunKineticsButtonPushed, true);
            app.RunKineticsButton.Text = 'Run Kinetics';
            app.STOPButton = uibutton(app.Kinetics_Controls, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.Cal_Controls = uipanel(app.DewanLabPIDUIFigure);
            app.Cal_Controls.Enable = 'off';
            app.Cal_Controls.Title = 'Experiment Controls (CAL)';
            app.Cal_Controls.Visible = 'off';
            app.RunCalibrationButton = uibutton(app.Cal_Controls, 'push');
            app.RunCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @RunCalibrationButtonPushed, true);
            app.RunCalibrationButton.Text = 'Run Calibration';
            app.STOPButton_4 = uibutton(app.Cal_Controls, 'push');
            app.STOPButton_4.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton_4.Text = 'STOP';
            app.PID_Controls_2 = uipanel(app.DewanLabPIDUIFigure);
            app.PID_Controls_2.Enable = 'off';
            app.PID_Controls_2.Title = 'Experiment Controls (PID 2)';
            app.PID_Controls_2.Visible = 'off';
            app.RetroButton = uibutton(app.PID_Controls_2, 'push');
            app.RetroButton.ButtonPushedFcn = createCallbackFcn(app, @RetroButtonPushed, true);
            app.RetroButton.Enable = 'off';
            app.RetroButton.Text = 'Retro';
            app.OrthoButton = uibutton(app.PID_Controls_2, 'push');
            app.OrthoButton.ButtonPushedFcn = createCallbackFcn(app, @OrthoButtonPushed, true);
            app.OrthoButton.Text = 'Ortho';
            app.STOPButton_5 = uibutton(app.PID_Controls_2, 'push');
            app.STOPButton_5.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton_5.Text = 'STOP';
            % Show the figure after all components are created
            app.DewanLabPIDUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pid_main_gui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DewanLabPIDUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DewanLabPIDUIFigure)
        end
    end
end