classdef pid_startup_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        main_figure                    matlab.ui.Figure
        CurrentCalibrationValuesPanel  matlab.ui.container.Panel
        SUBMITButton                   matlab.ui.control.Button
        CFEditField                    matlab.ui.control.NumericEditField
        CFLabel                        matlab.ui.control.Label
        x10EditField                   matlab.ui.control.NumericEditField
        x10Label                       matlab.ui.control.Label
        x5EditField                    matlab.ui.control.NumericEditField
        x5Label                        matlab.ui.control.Label
        x1EditField                    matlab.ui.control.NumericEditField
        x1Label                        matlab.ui.control.Label
        DewanLabPIDConfiguratorPanel   matlab.ui.container.Panel
        ExperimentTypeButtonGroup      matlab.ui.container.ButtonGroup
        CalibrationButton              matlab.ui.control.ToggleButton
        KineticsButton                 matlab.ui.control.ToggleButton
        CFButton                       matlab.ui.control.ToggleButton
        PIDButton                      matlab.ui.control.ToggleButton
        OdorNameEditField              matlab.ui.control.EditField
        OdorNameEditFieldLabel         matlab.ui.control.Label
        ExperimenterNameEditField      matlab.ui.control.EditField
        ExperimenterNameLabel          matlab.ui.control.Label
    end

    
    properties (Access = public)
        session_info = struct; % Return Settings
        finished = false;
    end
    
    properties (Access = private)
        % valid = false; % Description
    end
    
    methods (Access = private)
        
        function center_gui(app)
            app.main_figure.Visible = 'off';
            movegui(app.main_figure, 'center')
            app.main_figure.Visible = 'on';
        end
        
        function valid = validate_input(app)
            RED = [1 0 0];
            WHITE = [1 1 1];
            valid = true;


            if isempty(app.ExperimenterNameEditField.Value)
                app.ExperimenterNameLabel.FontColor = RED;
                valid = false;
            else
                app.ExperimenterNameLabel.FontColor = WHITE;
            end
    
            if isempty(app.OdorNameEditField.Value)
                app.OdorNameEditFieldLabel.FontColor = RED;
                valid=false;
            else
                app.OdorNameEditFieldLabel.FontColor = WHITE;
            end
    
            if app.x1EditField.Value == 0
                app.x1Label.FontColor = RED;
                valid=false;
            else
                app.x1Label.FontColor = WHITE;
            end
    
            if app.x5EditField.Value == 0
                app.x5Label.FontColor = RED;
                valid=false;
            else
                app.x5Label.FontColor = WHITE;
            end
    
            if app.x10EditField.Value == 0
                app.x10Label.FontColor = RED;
                valid=false;
            else
                app.x10Label.FontColor = WHITE;
            end
            if app.CFEditField.Value == 0
                app.CFLabel.FontColor = RED;
                valid=false;
            else
                app.CFLabel.FontColor = WHITE;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.center_gui();
        end

        % Button pushed function: SUBMITButton
        function SUBMITButtonPushed(app, event)
            name = app.ExperimenterNameEditField.Value;
            odor = app.OdorNameEditField.Value;
            x1 = app.x1EditField.Value;
            x5 = app.x5EditField.Value;
            x10 = app.x10EditField.Value;
            CF = app.CFEditField.Value;
            session_type = app.ExperimentTypeButtonGroup.SelectedObject.Text;

            valid = app.validate_input;

            if ~valid
                msgbox("Please correct the input in the red fields!", 'Error', "error");
            else
                app.session_info.name = name;
                app.session_info.odor = odor;
                app.session_info.x1 = x1;
                app.session_info.x5 = x5;
                app.session_info.x10 = x10;
                app.session_info.CF = CF;
                app.session_info.session_type = session_type;   
                app.finished = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.main_figure = uifigure('Visible', 'off');
            app.main_figure.Name = 'PID Configuator';
            app.DewanLabPIDConfiguratorPanel = uipanel(app.main_figure);
            app.DewanLabPIDConfiguratorPanel.Title = 'Dewan Lab PID Configurator';
            app.ExperimenterNameLabel = uilabel(app.DewanLabPIDConfiguratorPanel);
            app.ExperimenterNameLabel.Text = 'Experimenter Name:';
            app.ExperimenterNameEditField = uieditfield(app.DewanLabPIDConfiguratorPanel, 'text');
            app.OdorNameEditFieldLabel = uilabel(app.DewanLabPIDConfiguratorPanel);
            app.OdorNameEditFieldLabel.Text = 'Odor Name:';
            app.OdorNameEditField = uieditfield(app.DewanLabPIDConfiguratorPanel, 'text');
            app.ExperimentTypeButtonGroup = uibuttongroup(app.DewanLabPIDConfiguratorPanel);
            app.ExperimentTypeButtonGroup.Title = 'Experiment Type';
            app.PIDButton = uitogglebutton(app.ExperimentTypeButtonGroup);
            app.PIDButton.Text = 'PID';
            app.PIDButton.Value = true;
            app.CFButton = uitogglebutton(app.ExperimentTypeButtonGroup);
            app.CFButton.Text = 'CF';
            app.KineticsButton = uitogglebutton(app.ExperimentTypeButtonGroup);
            app.KineticsButton.Text = 'Kinetics';
            app.CalibrationButton = uitogglebutton(app.ExperimentTypeButtonGroup);
            app.CalibrationButton.Text = 'Calibration';
            app.CurrentCalibrationValuesPanel = uipanel(app.main_figure);
            app.CurrentCalibrationValuesPanel.Title = 'Current Calibration Values';
            app.x1Label = uilabel(app.CurrentCalibrationValuesPanel);
            app.x1Label.Text = 'x1:';
            app.x1EditField = uieditfield(app.CurrentCalibrationValuesPanel, 'numeric');
            app.x1EditField.Limits = [0 100];
            app.x1EditField.Value = 1;
            app.x5Label = uilabel(app.CurrentCalibrationValuesPanel);
            app.x5Label.Text = 'x5:';
            app.x5EditField = uieditfield(app.CurrentCalibrationValuesPanel, 'numeric');
            app.x5EditField.Limits = [0 100];
            app.x5EditField.Value = 1;
            app.x10Label = uilabel(app.CurrentCalibrationValuesPanel);
            app.x10Label.Text = 'x10:';
            app.x10EditField = uieditfield(app.CurrentCalibrationValuesPanel, 'numeric');
            app.x10EditField.Limits = [0 100];
            app.x10EditField.Value = 1;
            app.CFLabel = uilabel(app.CurrentCalibrationValuesPanel);
            app.CFLabel.Tag = 'CF';
            app.CFLabel.Text = 'CF:';
            app.CFEditField = uieditfield(app.CurrentCalibrationValuesPanel, 'numeric');
            app.CFEditField.Limits = [0 100];
            app.CFEditField.Value = 1;
            app.SUBMITButton = uibutton(app.CurrentCalibrationValuesPanel, 'push');
            app.SUBMITButton.ButtonPushedFcn = createCallbackFcn(app, @SUBMITButtonPushed, true);
            app.SUBMITButton.Text = 'SUBMIT';
            % Show the figure after all components are created
            app.main_figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pid_startup_gui

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.main_figure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.main_figure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.main_figure)
        end
    end
end