classdef pid_main_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Kinetics_Controls              matlab.ui.container.Panel
        STOPButton                     matlab.ui.control.Button
        RunKineticsButton              matlab.ui.control.Button
        PID_Controls                   matlab.ui.container.Panel
        STOPButton_3                   matlab.ui.control.Button
        SOLVENTSingleTrialButton       matlab.ui.control.Button
        ConcentrationAnalysisMOButton  matlab.ui.control.Button
        ConcentrationAnalysisSOLVENTButton  matlab.ui.control.Button
        Sensor_Status                  matlab.ui.container.Panel
        VoltageVGauge                  matlab.ui.control.SemicircularGauge
        VoltageVGaugeLabel             matlab.ui.control.Label
        PIDPeggedLamp                  matlab.ui.control.Lamp
        PIDPeggedLampLabel             matlab.ui.control.Label
        CF_Controls                    matlab.ui.container.Panel
        STOPButton_2                   matlab.ui.control.Button
        PureTrialButton                matlab.ui.control.Button
        CorrectionFactorButton         matlab.ui.control.Button
        ExperimentParametersPanel      matlab.ui.container.Panel
        LiveStatisticsPanel            matlab.ui.container.Panel
        FALSELabel                     matlab.ui.control.Label
        EstiamtedPPMEditField          matlab.ui.control.NumericEditField
        EstiamtedPPMEditFieldLabel     matlab.ui.control.Label
        SteadyStateLabel               matlab.ui.control.Label
        OdorPredurationEditField       matlab.ui.control.NumericEditField
        OdorPredurationLabel           matlab.ui.control.Label
        TrialPredurationEditField      matlab.ui.control.NumericEditField
        TrialPredurationLabel          matlab.ui.control.Label
        OdorDurationEditField          matlab.ui.control.NumericEditField
        OdorDurationLabel              matlab.ui.control.Label
        SubsampleEditField             matlab.ui.control.NumericEditField
        SubsampleLabel                 matlab.ui.control.Label
        NumofTrialsEditField           matlab.ui.control.NumericEditField
        NumofTrialsLabel               matlab.ui.control.Label
        PIDPumpDropDown                matlab.ui.control.DropDown
        PIDPumpDropDownLabel           matlab.ui.control.Label
        PIDGainDropDown                matlab.ui.control.DropDown
        PIDGainLabel                   matlab.ui.control.Label
        TrialTypeDropDown              matlab.ui.control.DropDown
        TrialTypeLabel                 matlab.ui.control.Label
        SpacerEditField                matlab.ui.control.NumericEditField
        SpacerLabel                    matlab.ui.control.Label
        ConcentrationVolumeEditField   matlab.ui.control.NumericEditField
        ConcentrationVolumeLabel       matlab.ui.control.Label
        DilutorMFCEditField            matlab.ui.control.NumericEditField
        DilutorMFCLabel                matlab.ui.control.Label
        CarrierMFCEditField            matlab.ui.control.NumericEditField
        CarrierMFCLabel                matlab.ui.control.Label
        ManualControlPanel             matlab.ui.container.Panel
        OdorLabel                      matlab.ui.control.Label
        VialValvesPanel                matlab.ui.container.Panel
        PresurizationPanel             matlab.ui.container.Panel
        PressurizeOdorLamp             matlab.ui.control.Lamp
        PressurizeOdorVialSwitch       matlab.ui.control.Switch
        PressurizeOdorVialSwitchLabel  matlab.ui.control.Label
        PressurizeSolventLamp          matlab.ui.control.Lamp
        PressurizeSolventVialSwitch    matlab.ui.control.Switch
        PressurizeSolventVialSwitchLabel  matlab.ui.control.Label
        OdorVialBottlePanel            matlab.ui.container.Panel
        Valve3Lamp                     matlab.ui.control.Lamp
        Valve3Switch                   matlab.ui.control.Switch
        Valve3SwitchLabel              matlab.ui.control.Label
        Valve4Lamp                     matlab.ui.control.Lamp
        Valve4Switch                   matlab.ui.control.Switch
        Valve4SwitchLabel              matlab.ui.control.Label
        SolventPureVialPanel           matlab.ui.container.Panel
        Valve1Lamp                     matlab.ui.control.Lamp
        Valve1Switch                   matlab.ui.control.Switch
        Valve1SwitchLabel              matlab.ui.control.Label
        Valve2Lamp                     matlab.ui.control.Lamp
        Valve2Switch                   matlab.ui.control.Switch
        Valve2SwitchLabel              matlab.ui.control.Label
        FinalValvesPanel               matlab.ui.container.Panel
        OdorFVLamp                     matlab.ui.control.Lamp
        OdorFinalValveSwitch           matlab.ui.control.Switch
        OdorFinalValveSwitchLabel      matlab.ui.control.Label
        ContaminationFVLamp            matlab.ui.control.Lamp
        ContaminationFinalValveSwitch  matlab.ui.control.Switch
        ContaminationFinalValveSwitchLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        pegged = false;
    end
    
    methods (Access = public)
        
        function update_voltage(app, value)
            if value > 10
                value = 10;
                app.PIDPeggedLamp.Color = [1 0 0];
                app.pegged = true;
            elseif value < 0
                value = 0;
            end

            app.animate_voltage(value);
            
        end
    end
    
    methods (Access = private)
        
        function animate_voltage(app, value)
            current_val = app.VoltageVGauge.Value;
            steps = linspace(current_val, value, 10);

            if current_val == value
                return
            else
                for i = 1:length(steps)
                    app.VoltageVGauge.Value = steps(i);
                    pause(0.02);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, trial_type)
            switch(trial_type)
                case "PID"
                    app.PID_Controls.Visible = "on";
                    app.PID_Controls.Enable = "on";
                case "CF"
                    app.CF_Controls.Visible = "on";
                    app.CF_Controls.Enable = "on";
                case "Kinetics"
                    app.Kinetics_Controls.Visible = "on";
                    app.Kinetics_Controls.Enable = "on";
                case "Calibration"
            end
        end

        % Value changed function: OdorFinalValveSwitch
        function OdorFinalValveSwitchValueChanged(app, event)
            value = app.OdorFinalValveSwitch.Value;
            if value == "On"
                app.OdorFVLamp.Color = [0, 1, 0];
            else
                app.OdorFVLamp.Color = [1, 0, 0];
            end
        end

        % Value changed function: ContaminationFinalValveSwitch
        function ContaminationFinalValveSwitchValueChanged(app, event)
            value = app.ContaminationFinalValveSwitch.Value;
            if value == "On"
                app.ContaminationFVLamp.Color = [0, 1, 0];
            else
                app.ContaminationFVLamp.Color = [1, 0, 0];
            end
        end

        % Value changed function: Valve1Switch
        function Valve1SwitchValueChanged(app, event)
            value = app.Valve1Switch.Value;
            if value == "On"
                app.Valve1Lamp.Color = [0, 1, 0];
            else
                app.Valve1Lamp.Color = [1, 0, 0];
            end
        end

        % Value changed function: Valve2Switch
        function Valve2SwitchValueChanged(app, event)
            value = app.Valve2Switch.Value;
            if value == "On"
                app.Valve2Lamp.Color = [0, 1, 0];
            else
                app.Valve2Lamp.Color = [1, 0, 0];
            end
        end

        % Value changed function: Valve3Switch
        function Valve3SwitchValueChanged(app, event)
            value = app.Valve3Switch.Value;

            if value == "On"
                app.Valve3Lamp.Color = [0, 1, 0];
            else
                app.Valve3Lamp.Color = [1, 0, 0];
            end
        end

        % Value changed function: Valve4Switch
        function Valve4SwitchValueChanged(app, event)
            value = app.Valve4Switch.Value;
            if value == "On"
                app.Valve4Lamp.Color = [0, 1, 0];
            else
                app.Valve4Lamp.Color = [1, 0, 0];
            end
        end

        % Value changed function: PressurizeOdorVialSwitch
        function PressurizeOdorVialSwitchValueChanged(app, event)
            value = app.PressurizeOdorVialSwitch.Value;
            if value == "On"
                app.PressurizeOdorLamp.Color = [0, 1, 0];
                app.Valve3Lamp.Color = [0, 1, 0];
                app.Valve4Lamp.Color = [0, 1, 0];
                app.Valve3Switch.Value = "On";
                app.Valve4Switch.Value = "On";
                app.Valve3Switch.Enable = false;
                app.Valve4Switch.Enable = false;


            else
                app.PressurizeOdorLamp.Color = [1, 0, 0];
                app.Valve3Lamp.Color = [1, 0, 0];
                app.Valve4Lamp.Color = [1, 0, 0];
                app.Valve3Switch.Value = "Off";
                app.Valve4Switch.Value = "Off";
                app.Valve3Switch.Enable = true;
                app.Valve4Switch.Enable = true;


            end
        end

        % Value changed function: PressurizeSolventVialSwitch
        function PressurizeSolventVialSwitchValueChanged(app, event)
            value = app.PressurizeSolventVialSwitch.Value;
            if value == "On"
                app.PressurizeSolventLamp.Color = [0, 1, 0];
                app.Valve1Lamp.Color = [0, 1, 0];
                app.Valve2Lamp.Color = [0, 1, 0];
                app.Valve1Switch.Value = "On";
                app.Valve2Switch.Value = "On";
                app.Valve1Switch.Enable = false;
                app.Valve2Switch.Enable = false;

            else
                app.PressurizeSolventLamp.Color = [1, 0, 0];
                app.Valve1Lamp.Color = [1, 0, 0];
                app.Valve2Lamp.Color = [1, 0, 0];
                app.Valve1Switch.Value = "Off";
                app.Valve2Switch.Value = "Off";
                app.Valve1Switch.Enable = true;
                app.Valve2Switch.Enable = true;

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ManualControlPanel = uipanel(app.UIFigure);
            app.ManualControlPanel.Title = 'Manual Control';
            app.FinalValvesPanel = uipanel(app.ManualControlPanel);
            app.FinalValvesPanel.Title = 'Final Valves';
            app.ContaminationFinalValveSwitchLabel = uilabel(app.FinalValvesPanel);
            app.ContaminationFinalValveSwitchLabel.Text = 'Contamination Final Valve';
            app.ContaminationFinalValveSwitch = uiswitch(app.FinalValvesPanel, 'slider');
            app.ContaminationFinalValveSwitch.ValueChangedFcn = createCallbackFcn(app, @ContaminationFinalValveSwitchValueChanged, true);
            app.ContaminationFVLamp = uilamp(app.FinalValvesPanel);
            app.OdorFinalValveSwitchLabel = uilabel(app.FinalValvesPanel);
            app.OdorFinalValveSwitchLabel.Text = 'Odor Final Valve';
            app.OdorFinalValveSwitch = uiswitch(app.FinalValvesPanel, 'slider');
            app.OdorFinalValveSwitch.ValueChangedFcn = createCallbackFcn(app, @OdorFinalValveSwitchValueChanged, true);
            app.OdorFVLamp = uilamp(app.FinalValvesPanel);
            app.VialValvesPanel = uipanel(app.ManualControlPanel);
            app.VialValvesPanel.Title = 'Vial Valves';
            app.SolventPureVialPanel = uipanel(app.VialValvesPanel);
            app.SolventPureVialPanel.Title = 'Solvent / Pure Vial';
            app.Valve2SwitchLabel = uilabel(app.SolventPureVialPanel);
            app.Valve2SwitchLabel.Text = 'Valve 2';
            app.Valve2Switch = uiswitch(app.SolventPureVialPanel, 'slider');
            app.Valve2Switch.ValueChangedFcn = createCallbackFcn(app, @Valve2SwitchValueChanged, true);
            app.Valve2Lamp = uilamp(app.SolventPureVialPanel);
            app.Valve1SwitchLabel = uilabel(app.SolventPureVialPanel);
            app.Valve1SwitchLabel.Text = 'Valve 1';
            app.Valve1Switch = uiswitch(app.SolventPureVialPanel, 'slider');
            app.Valve1Switch.ValueChangedFcn = createCallbackFcn(app, @Valve1SwitchValueChanged, true);
            app.Valve1Lamp = uilamp(app.SolventPureVialPanel);
            app.OdorVialBottlePanel = uipanel(app.VialValvesPanel);
            app.OdorVialBottlePanel.Title = 'Odor Vial / Bottle';
            app.Valve4SwitchLabel = uilabel(app.OdorVialBottlePanel);
            app.Valve4SwitchLabel.Text = 'Valve 4';
            app.Valve4Switch = uiswitch(app.OdorVialBottlePanel, 'slider');
            app.Valve4Switch.ValueChangedFcn = createCallbackFcn(app, @Valve4SwitchValueChanged, true);
            app.Valve4Lamp = uilamp(app.OdorVialBottlePanel);
            app.Valve3SwitchLabel = uilabel(app.OdorVialBottlePanel);
            app.Valve3SwitchLabel.Text = 'Valve 3';
            app.Valve3Switch = uiswitch(app.OdorVialBottlePanel, 'slider');
            app.Valve3Switch.ValueChangedFcn = createCallbackFcn(app, @Valve3SwitchValueChanged, true);
            app.Valve3Lamp = uilamp(app.OdorVialBottlePanel);
            app.PresurizationPanel = uipanel(app.VialValvesPanel);
            app.PresurizationPanel.Title = 'Presurization';
            app.PressurizeSolventVialSwitchLabel = uilabel(app.PresurizationPanel);
            app.PressurizeSolventVialSwitchLabel.Text = 'Pressurize Solvent Vial';
            app.PressurizeSolventVialSwitch = uiswitch(app.PresurizationPanel, 'slider');
            app.PressurizeSolventVialSwitch.ValueChangedFcn = createCallbackFcn(app, @PressurizeSolventVialSwitchValueChanged, true);
            app.PressurizeSolventLamp = uilamp(app.PresurizationPanel);
            app.PressurizeOdorVialSwitchLabel = uilabel(app.PresurizationPanel);
            app.PressurizeOdorVialSwitchLabel.Text = 'Pressurize Odor Vial';
            app.PressurizeOdorVialSwitch = uiswitch(app.PresurizationPanel, 'slider');
            app.PressurizeOdorVialSwitch.ValueChangedFcn = createCallbackFcn(app, @PressurizeOdorVialSwitchValueChanged, true);
            app.PressurizeOdorLamp = uilamp(app.PresurizationPanel);
            app.OdorLabel = uilabel(app.ManualControlPanel);
            app.OdorLabel.Text = 'Odor: ';
            app.ExperimentParametersPanel = uipanel(app.UIFigure);
            app.ExperimentParametersPanel.Title = 'Experiment Parameters';
            app.CarrierMFCLabel = uilabel(app.ExperimentParametersPanel);
            app.CarrierMFCLabel.Text = 'Carrier MFC:';
            app.CarrierMFCEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.CarrierMFCEditField.Limits = [0 900];
            app.CarrierMFCEditField.RoundFractionalValues = 'on';
            app.CarrierMFCEditField.Value = 300;
            app.DilutorMFCLabel = uilabel(app.ExperimentParametersPanel);
            app.DilutorMFCLabel.Text = 'Dilutor MFC:';
            app.DilutorMFCEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.DilutorMFCEditField.Limits = [0 900];
            app.DilutorMFCEditField.RoundFractionalValues = 'on';
            app.DilutorMFCEditField.Value = 600;
            app.ConcentrationVolumeLabel = uilabel(app.ExperimentParametersPanel);
            app.ConcentrationVolumeLabel.Text = 'Concentration / Volume:';
            app.ConcentrationVolumeEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.ConcentrationVolumeEditField.Limits = [0 Inf];
            app.SpacerLabel = uilabel(app.ExperimentParametersPanel);
            app.SpacerLabel.Text = 'Spacer:';
            app.SpacerEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.SpacerEditField.Limits = [0 Inf];
            app.SpacerEditField.Value = 0.375;
            app.TrialTypeLabel = uilabel(app.ExperimentParametersPanel);
            app.TrialTypeLabel.Text = 'Trial Type:';
            app.TrialTypeDropDown = uidropdown(app.ExperimentParametersPanel);
            app.TrialTypeDropDown.Items = {'Odor', 'Solvent', 'Pure'};
            app.TrialTypeDropDown.Value = 'Odor';
            app.PIDGainLabel = uilabel(app.ExperimentParametersPanel);
            app.PIDGainLabel.Text = 'PID Gain:';
            app.PIDGainDropDown = uidropdown(app.ExperimentParametersPanel);
            app.PIDGainDropDown.Items = {'x10', 'x5', 'x1'};
            app.PIDGainDropDown.Value = 'x10';
            app.PIDPumpDropDownLabel = uilabel(app.ExperimentParametersPanel);
            app.PIDPumpDropDownLabel.Text = 'PID Pump:';
            app.PIDPumpDropDown = uidropdown(app.ExperimentParametersPanel);
            app.PIDPumpDropDown.Items = {'low', 'med', 'high'};
            app.PIDPumpDropDown.Value = 'low';
            app.NumofTrialsLabel = uilabel(app.ExperimentParametersPanel);
            app.NumofTrialsLabel.Text = 'Num. of Trials:';
            app.NumofTrialsEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.NumofTrialsEditField.Limits = [0 Inf];
            app.NumofTrialsEditField.RoundFractionalValues = 'on';
            app.NumofTrialsEditField.Value = 200;
            app.SubsampleLabel = uilabel(app.ExperimentParametersPanel);
            app.SubsampleLabel.Text = 'Subsample:';
            app.SubsampleEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.SubsampleEditField.Limits = [0 Inf];
            app.SubsampleEditField.RoundFractionalValues = 'on';
            app.SubsampleEditField.Value = 5;
            app.OdorDurationLabel = uilabel(app.ExperimentParametersPanel);
            app.OdorDurationLabel.Text = 'Odor Duration:';
            app.OdorDurationEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.OdorDurationEditField.Limits = [0 Inf];
            app.OdorDurationEditField.RoundFractionalValues = 'on';
            app.OdorDurationEditField.Value = 2000;
            app.TrialPredurationLabel = uilabel(app.ExperimentParametersPanel);
            app.TrialPredurationLabel.Text = 'Trial Preduration:';
            app.TrialPredurationEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.TrialPredurationEditField.Limits = [0 Inf];
            app.TrialPredurationEditField.RoundFractionalValues = 'on';
            app.TrialPredurationEditField.Value = 1000;
            app.OdorPredurationLabel = uilabel(app.ExperimentParametersPanel);
            app.OdorPredurationLabel.Text = 'Odor Preduration:';
            app.OdorPredurationEditField = uieditfield(app.ExperimentParametersPanel, 'numeric');
            app.OdorPredurationEditField.Limits = [0 Inf];
            app.OdorPredurationEditField.RoundFractionalValues = 'on';
            app.OdorPredurationEditField.Value = 600;
            app.LiveStatisticsPanel = uipanel(app.ExperimentParametersPanel);
            app.LiveStatisticsPanel.Title = 'Live Statistics';
            app.SteadyStateLabel = uilabel(app.LiveStatisticsPanel);
            app.SteadyStateLabel.Text = 'Steady State:';
            app.EstiamtedPPMEditFieldLabel = uilabel(app.LiveStatisticsPanel);
            app.EstiamtedPPMEditFieldLabel.Text = 'Estiamted PPM:';
            app.EstiamtedPPMEditField = uieditfield(app.LiveStatisticsPanel, 'numeric');
            app.EstiamtedPPMEditField.Limits = [0 Inf];
            app.EstiamtedPPMEditField.RoundFractionalValues = 'on';
            app.EstiamtedPPMEditField.Value = 1000;
            app.FALSELabel = uilabel(app.LiveStatisticsPanel);
            app.FALSELabel.Text = 'FALSE';
            app.CF_Controls = uipanel(app.UIFigure);
            app.CF_Controls.Enable = 'off';
            app.CF_Controls.Title = 'Experiment Controls (CF)';
            app.CF_Controls.Visible = 'off';
            app.CorrectionFactorButton = uibutton(app.CF_Controls, 'push');
            app.CorrectionFactorButton.Text = 'Correction Factor';
            app.PureTrialButton = uibutton(app.CF_Controls, 'push');
            app.PureTrialButton.Text = 'Pure Trial';
            app.STOPButton_2 = uibutton(app.CF_Controls, 'push');
            app.STOPButton_2.Text = 'STOP';
            app.Sensor_Status = uipanel(app.UIFigure);
            app.Sensor_Status.Title = 'Sensor Status';
            app.PIDPeggedLampLabel = uilabel(app.Sensor_Status);
            app.PIDPeggedLampLabel.Text = 'PID Pegged:';
            app.PIDPeggedLamp = uilamp(app.Sensor_Status);
            app.VoltageVGaugeLabel = uilabel(app.Sensor_Status);
            app.VoltageVGaugeLabel.Text = 'Voltage (V)';
            app.VoltageVGauge = uigauge(app.Sensor_Status, 'semicircular');
            app.VoltageVGauge.Limits = [0 10];
            app.PID_Controls = uipanel(app.UIFigure);
            app.PID_Controls.Enable = 'off';
            app.PID_Controls.Title = 'Experiment Controls (PID)';
            app.PID_Controls.Visible = 'off';
            app.ConcentrationAnalysisSOLVENTButton = uibutton(app.PID_Controls, 'push');
            app.ConcentrationAnalysisSOLVENTButton.Text = 'Concentration Analysis (SOLVENT)';
            app.ConcentrationAnalysisMOButton = uibutton(app.PID_Controls, 'push');
            app.ConcentrationAnalysisMOButton.Text = 'Concentration Analysis (MO)';
            app.SOLVENTSingleTrialButton = uibutton(app.PID_Controls, 'push');
            app.SOLVENTSingleTrialButton.Text = 'SOLVENT: Single Trial';
            app.STOPButton_3 = uibutton(app.PID_Controls, 'push');
            app.STOPButton_3.Text = 'STOP';
            app.Kinetics_Controls = uipanel(app.UIFigure);
            app.Kinetics_Controls.Enable = 'off';
            app.Kinetics_Controls.Title = 'Experiment Controls (Kinetics)';
            app.Kinetics_Controls.Visible = 'off';
            app.RunKineticsButton = uibutton(app.Kinetics_Controls, 'push');
            app.RunKineticsButton.Text = 'Run Kinetics';
            app.STOPButton = uibutton(app.Kinetics_Controls, 'push');
            app.STOPButton.Text = 'STOP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pid_main_gui(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end